classdef banana_laplace_diagnostic_app < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure       matlab.ui.Figure
        UIAxes         matlab.ui.control.UIAxes
        Label          matlab.ui.control.Label
        lambda_slider  matlab.ui.control.Slider
        Label_2        matlab.ui.control.Label
        gamma_slider   matlab.ui.control.Slider
        lap_axes       matlab.ui.control.UIAxes
        Label_3        matlab.ui.control.Label
        alpha_slider   matlab.ui.control.Slider
    end

    
    properties (Access = private)
        f = @(x)(sqrt(3.0).*exp(x(:,1).^2.*(-1.0./6.0)-(x(:,2)-x(:,1).^2./2.0+3.0./2.0).*(x(:,2)./2.0-x(:,1).^2./4.0+3.0./4.0)))./(pi.*6.0)
        %f = @(x) sqrt(3)*(sqrt(3.0).*exp(3*x(:,1).^2.*(-1.0./6.0)-(x(:,2)-1.5-3*x(:,1).^2./2.0+3.0./2.0).*(-0.75+x(:,2)./2.0-3*x(:,1).^2./4.0+3.0./4.0)))./(pi.*6.0);
        s;
        s_trans;
        hess_at_mode;
        mode;
        f_at_mode;
        f_sym;
        m0_t;
        rot_det;
        f_interr
        gauss_interr;
        grid_bounds;
        grid_ratio;
        xvals;
        yvals;
        prior_mean_vals;
        grid_size1;
        grid_size2;
        postsurf;
        point_scat;
        true_surf;
        lap_approx;
        rot_scale_mat;
    end
    
    methods (Access = private)
        
        function c_t = gp_weights(app, x, lambda, gamma)
            c_t = pi*lambda^2*exp(-(vecnorm(x).^2 + vecnorm(app.s).^2)./(4*gamma^2) - vecnorm(x' - app.s).^2./(4*lambda^2));
        end
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, nu, r, delt)
            [x1, y1] = pol2cart(2*pi/nu*(0:(nu-1)), 1);
            app.s = [kron(delt:delt:r, [x1; y1]) [0; 0]];
            [app.mode, app.f_sym, app.f_at_mode, app.hess_at_mode, ~] = laplace_ingredients(app.f, 2, logical(0));
            app.hess_at_mode = double(app.hess_at_mode);
            app.mode = double(app.mode);
            app.f_at_mode = double(app.f_at_mode);
            app.m0_t = @(x) app.f_at_mode*exp(sum(((x-app.mode)*app.hess_at_mode).*(x-app.mode), 2)/2);
            app.rot_scale_mat = [sqrt(3) 0; 0 1];
            %[V, D] = eig(app.hess_at_mode);
            %app.rot_scale_mat = sqrt(-D)\V';
            %disp(app.rot_scale_mat);
            
            app.rot_det = abs(det(app.rot_scale_mat));
            app.s_trans = app.s'*app.rot_scale_mat + app.mode;
            
            app.grid_bounds = [min(app.s_trans(:,1))-4*delt max(app.s_trans(:,1))+4*delt...
                min(app.s_trans(:,2))-4*delt max(app.s_trans(:,2))+4*delt];
            %disp(app.grid_bounds)
            app.grid_ratio = (app.grid_bounds(4) - app.grid_bounds(3))/...
                (app.grid_bounds(2) - app.grid_bounds(1));
        
            app.gauss_interr = app.m0_t(app.s_trans);
            app.f_interr = app.f(app.s_trans);
            
            app.lap_approx = app.rot_det*app.f_at_mode*2*pi;
            
            [xgrid, ygrid] = meshgrid(uniquetol([linspace(app.grid_bounds(1), app.grid_bounds(2), 101) app.s_trans(:,1)'], 1e-12),...
                uniquetol([linspace(app.grid_bounds(3), app.grid_bounds(4), 101) app.s_trans(:,2)'], 1e-12));
            
            [app.grid_size1, app.grid_size2] = size(xgrid);
            app.xvals = xgrid(:);
%             disp(min(app.xvals));
%             disp(max(app.xvals));

            app.yvals = ygrid(:);
%             disp(min(app.yvals));
%             disp(max(app.yvals));
            
            
            app.prior_mean_vals = reshape(app.m0_t([app.xvals app.yvals]), app.grid_size1, app.grid_size2);
            app.true_surf = surf(app.UIAxes, unique(app.xvals(:)), unique(app.yvals(:)), ...
                reshape(app.f([app.xvals app.yvals]), app.grid_size1, app.grid_size2), 'FaceAlpha', 0.4, 'FaceColor', 'r', 'EdgeColor', 'none');
            hold(app.UIAxes, 'on')
            app.point_scat = scatter3(app.UIAxes, app.s_trans(:,1), app.s_trans(:,2), app.f_interr, 'filled', 'MarkerFaceColor', 'k');
            pbaspect(app.UIAxes, [1 app.grid_ratio geomean([app.grid_ratio 1/app.grid_ratio])])
            app.postsurf = gobjects(1);
        end

        % Value changed function: alpha_slider, gamma_slider, 
        % lambda_slider
        function SliderValuesChanged(app, event)
            lambda = app.lambda_slider.Value;
            gamma = 2^app.gamma_slider.Value;
            alpha = 10^app.alpha_slider.Value;

            delete(app.postsurf);
            hold(app.lap_axes, 'off')
            
            C0_t = se_decay_gram(app.s, lambda, gamma);
            pointz = C0_t\(app.f_interr - app.gauss_interr);
            c_t_cells = arrayfun(@(i) gp_weights(app, [app.xvals(i)/sqrt(3) app.yvals(i) + 1.5], lambda, gamma), 1:(app.grid_size1*app.grid_size2), 'UniformOutput', false);
            %c_t_cells = arrayfun(@(i) gp_weights(app, [app.xvals(i) app.yvals(i)]*app.rot_scale_mat' + app.mode', lambda, gamma), 1:(app.grid_size1*app.grid_size2), 'UniformOutput', false);

            c_t_vals = cat(1, c_t_cells{:});
            %disp(gp_weights(app, 0, 0, lambda, 1))
            %disp(post_gp_mean(app, 0, 0, lambda, 1))
            warning('off');
            app.postsurf = surf(app.UIAxes, unique(app.xvals(:)), unique(app.yvals(:)),...
                app.prior_mean_vals + reshape(c_t_vals*pointz, app.grid_size1, app.grid_size2),...
                'FaceAlpha', 0.65);
            legend(app.UIAxes, [app.postsurf app.true_surf app.point_scat], {'GP posterior mean', 'True target function', 'Interrogation points'},...
                'Location', 'northoutside');
            
            C0_cross = se_decay_cross_covar(app.s, lambda, gamma, -Inf, Inf);
            
            C0 = (16*gamma^4*lambda^4*pi^3)/(2*gamma^2 + lambda^2);
            post_var = app.rot_det^2*(C0 - (C0_cross/C0_t)*C0_cross')/alpha^2;
            
            post_mean = app.lap_approx + app.rot_det*C0_cross*pointz;
            
            lower_bound = double(min([norminv(0.025, post_mean, sqrt(post_var))...
                1 app.lap_approx]));
            upper_bound = double(max([norminv(0.975, post_mean, sqrt(post_var))...
                1 app.lap_approx]));
            lap_dist = fplot(app.lap_axes, @(x) normpdf(x, post_mean, sqrt(post_var)),...
                [lower_bound - 0.25*abs(lower_bound) upper_bound + 0.25*abs(upper_bound)], '-k');
            hold(app.lap_axes, 'on')
            lap_line = xline(app.lap_axes,app.lap_approx, '--b', 'LineWidth', 1);
            true_line = xline(app.lap_axes,1, '--r','LineWidth', 1);
            quant_line = xline(app.lap_axes,norminv(0.025, post_mean, sqrt(post_var)), ':k',...
                'LineWidth', 1.25);
            quant_line = xline(app.lap_axes,norminv(0.975, post_mean, sqrt(post_var)), ':k',...
                'LineWidth', 1.25);
            legend(app.lap_axes,[lap_dist, quant_line, lap_line, true_line],...
                {'Posterior of integral', '95% region',...
                'Laplace approximation value', 'True integral value'}, 'Location', 'southoutside');
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Full function and GP posterior mean')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = 'λ';
            app.lambda_slider = uislider(app.UIFigure);
            app.lambda_slider.Limits = [0.01 1.5];
            app.lambda_slider.MajorTicks = [0.01 0.1 0.2 0.3 0.4 0.5 0.6 0.7 0.8 0.9 1 1.1 1.2 1.3 1.4];
            app.lambda_slider.ValueChangedFcn = createCallbackFcn(app, @SliderValuesChanged, true);
            app.lambda_slider.MinorTicks = [];
            app.lambda_slider.Value = 0.5;
            app.Label_2 = uilabel(app.UIFigure);
            app.Label_2.Text = 'γ';
            app.gamma_slider = uislider(app.UIFigure);
            app.gamma_slider.Limits = [-1 7.64385618977472];
            app.gamma_slider.MajorTicks = [-1 0 1 2.32192809488736 3.32192809488736 4.64385618977472 5.64385618977472 6.64385618977472 7.64385618977472];
            app.gamma_slider.MajorTickLabels = {'0.5', '1', '2', '5', '10', '25', '50', '100', '200'};
            app.gamma_slider.ValueChangedFcn = createCallbackFcn(app, @SliderValuesChanged, true);
            app.gamma_slider.MinorTicks = [];
            app.gamma_slider.Value = 0.5;
            app.lap_axes = uiaxes(app.UIFigure);
            title(app.lap_axes, 'Laplace Approximation Diagnostic')
            xlabel(app.lap_axes, 'X')
            ylabel(app.lap_axes, 'Y')
            app.Label_3 = uilabel(app.UIFigure);
            app.Label_3.Text = 'α';
            app.alpha_slider = uislider(app.UIFigure);
            app.alpha_slider.Limits = [1 5];
            app.alpha_slider.MajorTicks = [1 1.69897000433602 2 2.69897000433602 3 4 5];
            app.alpha_slider.MajorTickLabels = {'10', '50', '100', '500', '1000', '10000', '100000'};
            app.alpha_slider.ValueChangedFcn = createCallbackFcn(app, @SliderValuesChanged, true);
            app.alpha_slider.MinorTicks = [];
            app.alpha_slider.Value = 1;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = banana_laplace_diagnostic_app(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end