classdef T_nD_diff_laplace_diagnostic_app < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        UIAxes                      matlab.ui.control.UIAxes
        LengthscaleLabel            matlab.ui.control.Label
        lambda_slider               matlab.ui.control.Slider
        PriorvariancedecayLabel     matlab.ui.control.Label
        gamma_slider                matlab.ui.control.Slider
        lap_axes                    matlab.ui.control.UIAxes
        PriorprecisionLabel         matlab.ui.control.Label
        alpha_slider                matlab.ui.control.Slider
        GeneratefiguretosaveButton  matlab.ui.control.Button
    end

    
    properties (Access = private)
        s; % Interrogation points on "normalized" scale
        rot_scale_mat; % Matrix used to rotate/scale/shift domain.
        rot_det; % Determinant of rot_scale_mat. Needed to put diagnostic back on "original" scale
        f_at_mode; % True function value at the mode
        f_interr % True function values at the interrogation points
        gauss_interr; % Prior mean (Gaussian approx.) values at interrogation points
        xvals; % Points on x_1 axis for plotting
        yvals; % Points on x_2 axis for plotting
        prior_mean_vals; % Values of prior Gaussian mean on a fine grid
        true_vals; % Values of true function on fine grid
        grid_size1; grid_size2; % Size of mesh grid used for plotting
        point_scat; % Scatterplot of interrogation points and the corresponding function values
        diff_surf; % Surface plot of GP posterior mean minus true function
        lap_approx; % Laplace approximation
        d; % Dimensionality of T-distribution
        v; % Degrees of freedom of T-distribution
        %pval;
    end
    
    methods (Access = private)
        
        function c_t = gp_weights(app, x, lambda, gamma, d)
            % Calculates the vector C0_t(x, s). Needed for the posterior
            % mean
            c_t = exp(-(vecnorm(x',2,1).^2 + vecnorm(app.s,2,1).^2)./(4*gamma^2) - vecnorm(x' - app.s,2,1).^2./(4*lambda^2));
        end
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, d, r, delt, v, ind)
            % d = Dimensionality of T-distribution
            % r = Number of standard deviations for which the grid should
            % extend.
            % delt = Increments (in standard deviations) at which to put
            % interrogation points. e.g. r = 3, delt = 1 puts
            % points at +-0,1,2,3 s.d.'s along each axis
            % v = Degrees of freedom for T-distribution (true function)
            % pval: level of significane to use for rejection. Consider
            % varying this with d
            % ind: logical; controls whether the true function is a
            % product of (independent) 1-D T-distributions (true) or a
            % multivariate (dependent, but uncorrelated) T-distribution
            % (false)
            app.v = v;
            app.d = d;
            %app.pval = pval;
            mode = zeros([1 app.d]);
            
            % True function
            if ind
                f = @(x) prod(tpdf(x, app.v), 2);
                hess_at_mode = diag(repelem(-(app.v+1)/app.v, app.d));
                app.f_at_mode = exp(d*(gammaln((app.v+1)/2) - gammaln(app.v/2) - log(app.v*pi)/2));
              
                
            else
                f = @(x) mvtpdf(x, eye(app.d), app.v);
                hess_at_mode = diag(repelem(-(app.v+app.d)/app.v, app.d));
                app.f_at_mode = exp(gammaln((app.v+app.d)/2) - gammaln(app.v/2) - d*log(app.v*pi)/2);
            end
            
            % Interrogation grid
            s_template = -r:delt:r;
            l = length(s_template);
            app.s = zeros([app.d app.d*(l-1)+1]);
            app.s(1,1:l) = s_template;
            for i = 2:app.d
                app.s(i,(i-2)*(l-1)+l+(1:(l-1))) = s_template([1:((l-1)/2) ((l+3)/2):end]);
            end
%             s_template = [-r:delt:-delt delt:delt:r];
%             l = length(s_template);
%             app.s = zeros([app.d app.d*l]);
%             app.s(1,1:l) = s_template;
%             for i = 1:app.d
%                 app.s(i,(i-1)*l+(1:l)) = s_template;
%             end
            %disp('Preliminary interrogation points made');
            
            % Stuff for Gaussian approximation
            %[mode, ~, f_at_mode, hess_at_mode, ~] = laplace_ingredients(f, d, false);
            m0_t = @(x) app.f_at_mode*exp(sum(((x-mode)*hess_at_mode).*(x-mode), 2)/2);
            
            % Stuff to scale ("normalize") domain
            [V, D] = eig(hess_at_mode);
            app.rot_scale_mat = sqrt(-D)\V';
            app.rot_det = abs(det(app.rot_scale_mat));
            s_trans = app.s'*app.rot_scale_mat + mode;
            %disp(s_trans(1:7,1:2))
            app.gauss_interr = m0_t(s_trans);
            app.f_interr = f(s_trans);
            %disp(log(app.f_interr) - log(app.f_at_mode))
            %disp(mean((app.f_interr -app.gauss_interr)/app.f_at_mode))
            app.lap_approx = app.rot_det*app.f_at_mode*(2*pi)^(app.d/2);
            
            % Stuff for plotting
            if app.d == 1
                title(app.UIAxes, 'GP post. mean minus full function');
                xlabel(app.UIAxes, 'x');
                grid_bounds = [min(s_trans)-2*app.rot_scale_mat(1,1) max(s_trans)+2*app.rot_scale_mat(1,1)];
                app.xvals = uniquetol([linspace(grid_bounds(1), grid_bounds(2), 101) s_trans'], 1e-12);
                app.grid_size1 = length(app.xvals);
                app.prior_mean_vals = m0_t(app.xvals');
                app.true_vals = f(app.xvals');
                app.point_scat = scatter(app.UIAxes, s_trans, repelem(0, length(app.f_interr)), 'filled', 'MarkerFaceColor', 'k');
                hold(app.UIAxes, 'on')
                app.diff_surf = gobjects(1);
                
            elseif app.d == 2
                title(app.UIAxes, 'GP post. mean minus full function');
                xlabel(app.UIAxes, 'x_1');
                ylabel(app.UIAxes, 'x_2');
                grid_bounds = [min(s_trans(:,1))-3*app.rot_scale_mat(1,1) max(s_trans(:,1))+3*app.rot_scale_mat(1,1)...
                    min(s_trans(:,2))-3*app.rot_scale_mat(2,2) max(s_trans(:,2))+3*app.rot_scale_mat(2,2)];
                grid_ratio = (grid_bounds(4) - grid_bounds(3))/...
                    (grid_bounds(2) - grid_bounds(1));
                [xgrid, ygrid] = meshgrid(uniquetol([linspace(grid_bounds(1), grid_bounds(2), 101) s_trans(:,1)'], 1e-12),...
                    uniquetol([linspace(grid_bounds(3), grid_bounds(4), 101) s_trans(:,2)'], 1e-12));
                [app.grid_size1, app.grid_size2] = size(xgrid);
                app.xvals = xgrid(:);
                app.yvals = ygrid(:);
                app.prior_mean_vals = reshape(m0_t([app.xvals app.yvals]), app.grid_size1, app.grid_size2);
                app.true_vals = reshape(f([app.xvals app.yvals]), app.grid_size1, app.grid_size2);
                app.point_scat = scatter3(app.UIAxes, s_trans(:,1), s_trans(:,2), repelem(0, length(app.f_interr)), 'filled', 'MarkerFaceColor', 'k');
                hold(app.UIAxes, 'on')
                pbaspect(app.UIAxes, [1 grid_ratio geomean([grid_ratio 1/grid_ratio])])
                app.diff_surf = gobjects(1);
                
            else
                title(app.UIAxes, {'2-D slice of GP post. mean minus full function', '(x_i = 0 for i > 2)'});
                xlabel(app.UIAxes, 'x_1');
                ylabel(app.UIAxes, 'x_2');
                grid_bounds = [min(s_trans(:,1))-3*app.rot_scale_mat(1,1) max(s_trans(:,1))+3*app.rot_scale_mat(1,1)...
                    min(s_trans(:,2))-3*app.rot_scale_mat(2,2) max(s_trans(:,2))+3*app.rot_scale_mat(2,2)];
                grid_ratio = (grid_bounds(4) - grid_bounds(3))/...
                    (grid_bounds(2) - grid_bounds(1));
                [xgrid, ygrid] = meshgrid(uniquetol([linspace(grid_bounds(1), grid_bounds(2), 101) s_trans(:,1)'], 1e-12),...
                    uniquetol([linspace(grid_bounds(3), grid_bounds(4), 101) s_trans(:,2)'], 1e-12));
                [app.grid_size1, app.grid_size2] = size(xgrid);
                app.xvals = xgrid(:);
                app.yvals = ygrid(:);
                app.prior_mean_vals = reshape(m0_t([app.xvals app.yvals repelem(0*sqrt(app.rot_det),length(app.xvals), app.d-2)]), app.grid_size1, app.grid_size2);
                app.true_vals = reshape(f([app.xvals app.yvals repelem(0*sqrt(app.rot_det), length(app.xvals), app.d-2)]), app.grid_size1, app.grid_size2);
                app.point_scat = scatter3(app.UIAxes, s_trans(1:(2*l-1),1), s_trans(1:(2*l-1),2), repelem(0, length(app.f_interr(1:(2*l-1)))), 'filled', 'MarkerFaceColor', 'k');
                hold(app.UIAxes, 'on')
                pbaspect(app.UIAxes, [1 grid_ratio geomean([grid_ratio 1/grid_ratio])])
                app.diff_surf = gobjects(1);
            end
        end

        % Value changed function: alpha_slider, gamma_slider, 
        % lambda_slider
        function SliderValuesChanged(app, event)
            lambda = app.lambda_slider.Value;
            disp(['lambda: ' num2str(lambda)]);
            %gamma = 2^app.gamma_slider.Value;
            gamma = Inf;
            disp(['gamma: ' num2str(gamma)]);
            alpha = 10^app.alpha_slider.Value;
            disp(['alpha: ' num2str(alpha)]);
            
            % Initialize
            hold(app.lap_axes, 'off')
            
            % Get necessary stuff to calculate posterior GP mean given
            % hyperparameters
            C0_t = se_decay_gram(app.s, lambda, gamma);
            pointz = C0_t\(exp(vecnorm(app.s,2,1).^2/2)'.*(app.f_interr - app.gauss_interr));

            if app.d == 1
                delete(app.diff_surf);

                c_t_cells = arrayfun(@(i) gp_weights(app, app.xvals(i)./app.rot_scale_mat(1,1), lambda, gamma, app.d),...
                    1:(app.grid_size1), 'UniformOutput', false);
                c_t_vals = cat(1, c_t_cells{:});

                warning('off');
                app.diff_surf = plot(app.UIAxes, app.xvals, app.prior_mean_vals + c_t_vals*pointz - app.true_vals);
                legend(app.UIAxes, [app.point_scat], {'Interrogation points'},...
                    'Location', 'northoutside');
                
            elseif app.d == 2
                delete(app.diff_surf);
                
                c_t_cells = arrayfun(@(i) gp_weights(app, [app.xvals(i)./app.rot_scale_mat(1,1) app.yvals(i)./app.rot_scale_mat(2,2)], lambda, gamma, app.d),...
                    1:(app.grid_size1*app.grid_size2), 'UniformOutput', false);
                c_t_vals = cat(1, c_t_cells{:});

                warning('off');
                app.diff_surf = surf(app.UIAxes, unique(app.xvals(:)), unique(app.yvals(:)),...
                    app.prior_mean_vals + reshape(c_t_vals*pointz, app.grid_size1, app.grid_size2) - app.true_vals,...
                    'FaceAlpha', 0.65);
                legend(app.UIAxes, [app.point_scat], {'Interrogation points'},...
                    'Location', 'northoutside');
                
            else
                delete(app.diff_surf);

                c_t_cells = arrayfun(@(i) gp_weights(app, [app.xvals(i)./app.rot_scale_mat(1,1) app.yvals(i)./app.rot_scale_mat(2,2) repelem(0, 1, app.d-2)], lambda, gamma, app.d),...
                    1:(app.grid_size1*app.grid_size2), 'UniformOutput', false);
                c_t_vals = cat(1, c_t_cells{:});
                
                gauss_weights = arrayfun(@(i) exp(-((app.xvals(i)./app.rot_scale_mat(2,2)).^2 + (app.yvals(i)./app.rot_scale_mat(2,2)).^2)/2),...
                    1:(app.grid_size1*app.grid_size2), 'UniformOutput', false);
                gauss_weights = reshape(cat(1, gauss_weights{:}), app.grid_size1, app.grid_size2);
                
                % Used this bit for sanity checks to see how different
                % shapes affected the behaviuor
%                 disp('normalized interrogation differences:');
%                 disp((app.f_interr - app.gauss_interr)/app.f_at_mode);
%                 disp('weights:');
%                 disp(c_t_vals(6865,:)/C0_t);
%                 disp('Normalized correction:');
%                 disp(c_t_vals(6865,:)*pointz/app.f_at_mode);
%                 disp('Normalized prior mean value:');
%                 disp(app.prior_mean_vals(40,66)/app.f_at_mode);
%                 disp('Normalized true value:');
%                 disp(app.true_vals(40,66)/app.f_at_mode);
%                 disp('Normalized prior difference:');
%                 disp((app.true_vals(40,66) - app.prior_mean_vals(40,66))/app.f_at_mode);
%                 disp('Normalized posterior difference:');
%                 disp((app.true_vals(40,66) - app.prior_mean_vals(40,66) - c_t_vals(6865,:)*pointz)/app.f_at_mode);

                warning('off');
                app.diff_surf = surf(app.UIAxes, unique(app.xvals(:)), unique(app.yvals(:)),...
                    app.prior_mean_vals + gauss_weights.*reshape(c_t_vals*pointz, app.grid_size1, app.grid_size2) - app.true_vals,...
                    'FaceAlpha', 0.65);
%                 app.diff_surf = surf(app.UIAxes, unique(app.xvals(:)), unique(app.yvals(:)),...
%                     app.prior_mean_vals - app.true_vals,...
%                     'FaceAlpha', 0.65);
                legend(app.UIAxes, [app.point_scat], {'Interrogation points'},...
                    'Location', 'northoutside');
            end
            
            % Get posterior mean and variance for integral diagnostic
            C0_cross = se_decay_cross_covar(app.s, lambda, gamma, -Inf, Inf);
            log_C0 = app.d*(log((4*gamma^2*lambda^2*pi^(3/2))/(2*gamma^2 + lambda^2)^(1/2))-log(alpha));
            %disp(C0);
            
            post_var = exp(2*(log(app.f_at_mode)+log(app.rot_det))+log_C0) - ...
                exp(2*(log(app.f_at_mode)+log(app.rot_det)) + app.d*(log(gamma^2*lambda^3*sqrt(pi^3)/(gamma^2+lambda^2)) - log(alpha)))*(C0_cross/C0_t)*C0_cross'; 
            %disp(app.f_at_mode^2*app.rot_det^2);
            %disp(exp(app.d*(log(gamma^2*lambda^3*sqrt(pi^3)/(gamma^2+lambda^2)) - log(alpha)))*(C0_cross/C0_t)*C0_cross');
            disp(post_var);
            
            %disp(log(app.rot_det));
            %disp(app.f_interr/app.f_at_mode);
            %disp(log(app.f_at_mode));
            %disp(exp(log(app.rot_det) +app.d*log(lambda*gamma*sqrt(pi)/sqrt(lambda^2+gamma^2)))*C0_cross*pointz);
            post_mean = app.lap_approx + exp(log(app.rot_det) +app.d*log(lambda*gamma*sqrt(pi)/sqrt(lambda^2+gamma^2)))*C0_cross*pointz;
            
            %disp(C0_cross*pointz);
            %disp(log(app.rot_det) +app.d*log(lambda*gamma*sqrt(pi)/sqrt(lambda^2+gamma^2)));
            disp(post_mean - 1);
            % Plot integral diagnostic
            lower_bound = double(min([norminv(0.025, post_mean, sqrt(post_var))...
                1 app.lap_approx]));
            upper_bound = double(max([norminv(0.975, post_mean, sqrt(post_var))...
                1 app.lap_approx]));
            lap_dist = fplot(app.lap_axes, @(x) normpdf(x, post_mean, sqrt(post_var)),...
                [lower_bound - 0.25*(upper_bound - lower_bound) upper_bound + 0.25*(upper_bound - lower_bound)], '-k');
            hold(app.lap_axes, 'on')
            lap_line = xline(app.lap_axes, ...
                app.lap_approx, '--b', 'LineWidth', 1);
            true_line = xline(app.lap_axes, 1, '--r','LineWidth', 1);
%             quant_line = xline(app.lap_axes,norminv(0.025, post_mean, sqrt(post_var)), ':k',...
%                 'LineWidth', 1.25);
%             quant_line = xline(app.lap_axes,norminv(0.975, post_mean, sqrt(post_var)), ':k',...
%                 'LineWidth', 1.25);
%             %If using pval, replace 0.025 and 0.975 with (1-pval)/2 and
%             %(1+pval)/2, respectively
%             legend(app.lap_axes,[lap_dist, quant_line, lap_line, true_line],...
%                 {'Posterior of integral', '95% region',...
%                 'Laplace approximation value', 'True integral value'}, 'Location', 'southoutside');
        end

        % Button pushed function: GeneratefiguretosaveButton
        function GeneratefiguretosaveButtonPushed(app, event)
            
            
            fig = tiledlayout(1,3, 'TileSpacing', 'Compact', 'Padding', 'Compact');
            title(fig, strcat(['\lambda = ' num2str(app.lambda_slider.Value) ', \gamma = ' num2str(2^app.gamma_slider.Value)...
                ', \alpha = ' num2str(10^app.alpha_slider.Value)]));
            %hSub1 = subplot('Position', [0 0 1 1]);
            hSub1 = nexttile([1 2]);
            %disp(app.UIAxes.Children);
            copyobj(app.UIAxes.Children, hSub1);
            if app.d == 1
                xlabel('x');
            else
                xlabel('x_1');
                ylabel('x_2');
            end
            pbaspect(hSub1, app.UIAxes.PlotBoxAspectRatio)
            legend('Interrogation points', 'Location', 'southoutside');
            title(strcat(['m_1^t - T_{' num2str(app.v) ',' num2str(app.d) '}']));
            
            hSub2 = nexttile;
            copyobj(app.lap_axes.Children, hSub2);
            legend({'Posterior distribution of integral', strcat(['L(T_{' num2str(app.v) ',' num2str(app.d) '})']), 'F', '95% region'}, 'Location', ...
                'southoutside');
            title('Laplace Approximation Diagnostic')
            
            %copyobj(app.UIAxes.Legend, hSub1);
            %sub2 = copyobj(app.lap_axes.Parent, fig);
            %saveas(fig, 'fig.png');
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, '')
            xlabel(app.UIAxes, '')
            ylabel(app.UIAxes, '')
            app.LengthscaleLabel = uilabel(app.UIFigure);
            app.LengthscaleLabel.Text = 'Length-scale: λ';
            app.lambda_slider = uislider(app.UIFigure);
            app.lambda_slider.Limits = [0.1 5];
            app.lambda_slider.MajorTicks = [0.01 0.1 0.2 0.3 0.4 0.5 0.6 0.7 0.8 0.9 1 1.1 1.2 1.3 1.4 1.5];
            app.lambda_slider.ValueChangedFcn = createCallbackFcn(app, @SliderValuesChanged, true);
            app.lambda_slider.MinorTicks = [];
            app.lambda_slider.Value = 1;
            app.PriorvariancedecayLabel = uilabel(app.UIFigure);
            app.PriorvariancedecayLabel.Text = 'Prior variance decay: γ';
            app.gamma_slider = uislider(app.UIFigure);
            app.gamma_slider.Limits = [-1 6];
            app.gamma_slider.MajorTicks = [-1 0 1 2.32192809488736 3.32192809488736 4.64385618977472 5.64385618977472 6.64385618977472 7.64385618977472];
            app.gamma_slider.MajorTickLabels = {'0.5', '1', '2', '5', '10', '25', '50', '100', '200'};
            app.gamma_slider.ValueChangedFcn = createCallbackFcn(app, @SliderValuesChanged, true);
            app.gamma_slider.MinorTicks = [];
            app.gamma_slider.Value = -0.19660762117295;
            app.lap_axes = uiaxes(app.UIFigure);
            title(app.lap_axes, 'Laplace Approximation Diagnostic')
            xlabel(app.lap_axes, '')
            ylabel(app.lap_axes, '')
            app.PriorprecisionLabel = uilabel(app.UIFigure);
            app.PriorprecisionLabel.Text = 'Prior precision: α';
            app.alpha_slider = uislider(app.UIFigure);
            app.alpha_slider.Limits = [0 0.602059991327962];
            app.alpha_slider.MajorTicks = [0 0.698970004336019 1 1.69897000433602 2 3 4];
            app.alpha_slider.MajorTickLabels = {'1', '5', '10', '50', '100', '1000', '10000'};
            app.alpha_slider.ValueChangedFcn = createCallbackFcn(app, @SliderValuesChanged, true);
            app.alpha_slider.MinorTicks = [];
            app.alpha_slider.Value = 0.493290714306025;
            app.GeneratefiguretosaveButton = uibutton(app.UIFigure, 'push');
            app.GeneratefiguretosaveButton.ButtonPushedFcn = createCallbackFcn(app, @GeneratefiguretosaveButtonPushed, true);
            app.GeneratefiguretosaveButton.Text = 'Generate figure to save';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = T_nD_diff_laplace_diagnostic_app(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end